classdef Initial_Behavior_Analysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClearLogButton               matlab.ui.control.Button
        LogTextArea                  matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        SessionDropDown              matlab.ui.control.DropDown
        SessionDropDownLabel         matlab.ui.control.Label
        AnimalDropDown               matlab.ui.control.DropDown
        AnimalDropDownLabel          matlab.ui.control.Label
        GetInitialAnalysisBehaviorButton  matlab.ui.control.Button
        IntialBehaviorAnalysisLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        paths % Description
        SessionList
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SessionDropDown.Enable = "off";
            app.SessionList = '';
        end

        % Drop down opening function: AnimalDropDown
        function AnimalDropDownOpening(app, event)
            app.paths = setPaths(source);
            oldfolder = cd(app.paths.raw_behavior_data);
            Files = ls('*.mat');
            Files_Name = strsplit(Files,'.mat');
            Animal = strtok(Files_Name,'_');
            Animal = unique(Animal,'first');
            Animal(1) = [];
            Animal = strtrim(Animal);
            Index = find(contains(Animal,'beh'));
            Animal(Index)=[];
            Animal = ['Selection',Animal];
            app.AnimalDropDown.Items = Animal;
            cd(oldfolder)
        end

        % Drop down opening function: SessionDropDown
        function SessionDropDownOpening(app, event)
            oldfolder = cd(app.paths.processed_behavior_data);
            B_Data = ls('*.mat');
            B_Data = strsplit(B_Data,'.mat');
            MB_Data = strtok(B_Data,'_');
            MB_Data = strtrim(MB_Data);
            LIndex = strcmpi(MB_Data,app.AnimalDropDown.Value);
            Index = find(LIndex==1);
            Session_List = B_Data(Index);
            Session_List = ['Selection',Session_List];
            app.SessionDropDown.Items = Session_List;
            cd(oldfolder)      
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
            if strcmpi(value,'Selection')
                app.SessionDropDown.Enable = "off";
            else
                app.SessionDropDown.Enable = "on";
            end
        end

        % Button pushed function: GetInitialAnalysisBehaviorButton
        function GetInitialAnalysisBehaviorButtonPushed(app, event)
            if strcmpi(app.SessionDropDown.Value,'Selection')
                app.LogTextArea.Value = 'Nothing Selected.';
                return
            else
            end
            Session = split(app.SessionList,',');
            Session = strtrim(Session);
            Session(end)=[];
            for i = 1:length(Session)
                [Behavior_analysis] = IBehavioral_Analysis(Session(i),app.paths);
                Behavior_Analysis_List(i).Session = Behavior_analysis;
                Behavior_Analysis_List(i).Name = Session(i);
            end
            assignin("base","Behavior_Analysis_List",Behavior_Analysis_List)
            Plot_IBehavior_Analysis(Behavior_Analysis_List);
        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            value = app.SessionDropDown.Value;
            app.SessionList = append(string(app.SessionList),string(value),',');
            app.LogTextArea.Value = app.SessionList;
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed(app, event)
            app.SessionList = '';
            app.LogTextArea.Value = app.SessionList;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IntialBehaviorAnalysisLabel = uilabel(app.UIFigure);
            app.IntialBehaviorAnalysisLabel.Text = 'Intial Behavior Analysis';
            app.GetInitialAnalysisBehaviorButton = uibutton(app.UIFigure, 'push');
            app.GetInitialAnalysisBehaviorButton.ButtonPushedFcn = createCallbackFcn(app, @GetInitialAnalysisBehaviorButtonPushed, true);
            app.GetInitialAnalysisBehaviorButton.Text = 'Get Initial Analysis Behavior';
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal:';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.Items = {'Selection'};
            app.AnimalDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AnimalDropDownOpening, true);
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.AnimalDropDown.Value = 'Selection';
            app.SessionDropDownLabel = uilabel(app.UIFigure);
            app.SessionDropDownLabel.Text = 'Session:';
            app.SessionDropDown = uidropdown(app.UIFigure);
            app.SessionDropDown.Items = {'Selection'};
            app.SessionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SessionDropDownOpening, true);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.SessionDropDown.Value = 'Selection';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log:';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.ClearLogButton = uibutton(app.UIFigure, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed, true);
            app.ClearLogButton.Text = 'Clear Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Initial_Behavior_Analysis_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end